classdef Panel_uzytkownika < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametryodwrconegowahadaPanel  matlab.ui.container.Panel
        MasawzkakgSpinnerLabel          matlab.ui.control.Label
        MasawzkakgSpinner               matlab.ui.control.Spinner
        MasawahadakgLabel               matlab.ui.control.Label
        MasawahadlaSpinner              matlab.ui.control.Spinner
        WspczynniktarciawiskotycznegoNsmLabel  matlab.ui.control.Label
        wspolczynnik_tarcia_wiskotycznegoSpinner  matlab.ui.control.Spinner
        OdlegorodkacikociwahadaodosiobrotumLabel  matlab.ui.control.Label
        dlugosc_wahadlaSpinner          matlab.ui.control.Spinner
        ZapiszButton                    matlab.ui.control.Button
        WagistrojeniaregulatorwPanel    matlab.ui.container.Panel
        PrzemieszczenielinioweLabel     matlab.ui.control.Label
        linioweSpinner                  matlab.ui.control.Spinner
        PrzemieszczeniektoweLabel       matlab.ui.control.Label
        katoweSpinner                   matlab.ui.control.Spinner
        SianapduLabel                   matlab.ui.control.Label
        sila_napeduSpinner              matlab.ui.control.Spinner
        Zapis_wagButton                 matlab.ui.control.Button
        SymulacjaPanel                  matlab.ui.container.Panel
        SymulacjaodwrconegowahadaButton  matlab.ui.control.Button
        TypRegulatoraButtonGroup        matlab.ui.container.ButtonGroup
        LqrButton                       matlab.ui.control.RadioButton
        NumericButton                   matlab.ui.control.RadioButton
        StrojeniePanel                  matlab.ui.container.Panel
        RegulatorLQRButton              matlab.ui.control.Button
        RegulatornumerycznyButton       matlab.ui.control.Button
        Reset_nastawButton              matlab.ui.control.Button
        ZakceniePanel                   matlab.ui.container.Panel
        TypzakceniaButtonGroup          matlab.ui.container.ButtonGroup
        wymuszenieimpulsoweButton       matlab.ui.control.RadioButton
        skokButton                      matlab.ui.control.RadioButton
        szumbialyButton                 matlab.ui.control.RadioButton
        wymuszeniesinusoidalneButton    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        PANELUYTKOWNIKAODWRCONEGOWAHADALabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: SymulacjaodwrconegowahadaButton
        function SymulacjaodwrconegowahadaButtonPushed(app, event)
            open('wahadlo_odwrocone.m') 
        end

        % Button pushed function: RegulatornumerycznyButton
        function RegulatornumerycznyButtonPushed(app, event)
            open('strojenie_wahadla_ackerman.m')
        end

        % Button pushed function: RegulatorLQRButton
        function RegulatorLQRButtonPushed(app, event)
            open('strojenie_lqr.m')
        end

        % Button pushed function: Reset_nastawButton
        function Reset_nastawButtonPushed(app, event)
            run reset_nastawy
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)

L=app.dlugosc_wahadlaSpinner.Value;
m=app.MasawahadlaSpinner.Value;
M=app.MasawzkakgSpinner.Value;
b=app.wspolczynnik_tarcia_wiskotycznegoSpinner.Value;

     save('dane.mat','M','m','L','b' )
    

        end

        % Button pushed function: Zapis_wagButton
        function Zapis_wagButtonPushed(app, event)
wf=app.sila_napeduSpinner.Value;
wp=app.linioweSpinner.Value;
wk=app.katoweSpinner.Value;

save('wagi.mat', 'wf','wp','wk')
        end

        % Selection changed function: TypzakceniaButtonGroup
        function TypzakceniaButtonGroupSelectionChanged(app, event)
            sinusoidalne=app.wymuszeniesinusoidalneButton.Value;
            szum=app.szumbialyButton.Value;
            skok=app.skokButton.Value;
            impuls=app.wymuszenieimpulsoweButton.Value;

                czas=0:0.01:10;

              if sinusoidalne==1 
                y=sin(2*pi*4*czas);
                plot(app.UIAxes,czas, y);  
                
                typ_zaklocen=4;            
                save('typ_zaklocenia.mat', 'typ_zaklocen'); 
              elseif skok==1
                y(1)=0;
                y(2:length(czas))=1;
                plot(app.UIAxes,czas, y);  
                
                typ_zaklocen=2;
                save('typ_zaklocenia.mat', 'typ_zaklocen');
              elseif szum==1
                y=wgn(length(czas),1,1);
                plot(app.UIAxes,czas, y);  
                
                typ_zaklocen=3;
                save('typ_zaklocenia.mat', 'typ_zaklocen');
                
              elseif impuls==1
                y(1)=1;
                y(2:length(czas))=0;
                plot(app.UIAxes,czas, y);  
                
                typ_zaklocen=1;
                save('typ_zaklocenia.mat', 'typ_zaklocen');  
               end 


        end

        % Selection changed function: TypRegulatoraButtonGroup
        function TypRegulatoraButtonGroupSelectionChanged(app, event)
           numeric=app.NumericButton.Value;
           lqr=app.LqrButton.Value;
           if numeric==1
                typ_regulator=1;
                save('typ_regulatora.mat', 'typ_regulator');
           elseif lqr==1
                typ_regulator=0;
                save('typ_regulatora.mat', 'typ_regulator');

           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.ParametryodwrconegowahadaPanel = uipanel(app.UIFigure);
            app.ParametryodwrconegowahadaPanel.Title = 'Parametry odwróconego wahadła';
            app.MasawzkakgSpinnerLabel = uilabel(app.ParametryodwrconegowahadaPanel);
            app.MasawzkakgSpinnerLabel.Text = 'Masa wózka [kg]';
            app.MasawzkakgSpinner = uispinner(app.ParametryodwrconegowahadaPanel);
            app.MasawzkakgSpinner.Value = 5;
            app.MasawahadakgLabel = uilabel(app.ParametryodwrconegowahadaPanel);
            app.MasawahadakgLabel.Text = 'Masa wahadła [kg]';
            app.MasawahadlaSpinner = uispinner(app.ParametryodwrconegowahadaPanel);
            app.MasawahadlaSpinner.Value = 2;
            app.WspczynniktarciawiskotycznegoNsmLabel = uilabel(app.ParametryodwrconegowahadaPanel);
            app.WspczynniktarciawiskotycznegoNsmLabel.Text = {'Współczynnik tarcia'; 'wiskotycznego [Ns/m]'};
            app.wspolczynnik_tarcia_wiskotycznegoSpinner = uispinner(app.ParametryodwrconegowahadaPanel);
            app.wspolczynnik_tarcia_wiskotycznegoSpinner.Value = 1;
            app.OdlegorodkacikociwahadaodosiobrotumLabel = uilabel(app.ParametryodwrconegowahadaPanel);
            app.OdlegorodkacikociwahadaodosiobrotumLabel.Text = {'Odległość środka'; 'ciężkości wahadła '; 'od osi obrotu [m]'; ''};
            app.dlugosc_wahadlaSpinner = uispinner(app.ParametryodwrconegowahadaPanel);
            app.dlugosc_wahadlaSpinner.Value = 3;
            app.ZapiszButton = uibutton(app.ParametryodwrconegowahadaPanel, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Tooltip = {'Zmiana parametrów wymaga zapisania przed strojeniem lub symulacją'};
            app.ZapiszButton.Text = 'Zapisz';
            app.WagistrojeniaregulatorwPanel = uipanel(app.UIFigure);
            app.WagistrojeniaregulatorwPanel.Title = 'Wagi strojenia regulatorów';
            app.PrzemieszczenielinioweLabel = uilabel(app.WagistrojeniaregulatorwPanel);
            app.PrzemieszczenielinioweLabel.Text = {'Przemieszczenie '; 'liniowe'};
            app.linioweSpinner = uispinner(app.WagistrojeniaregulatorwPanel);
            app.linioweSpinner.Value = 1;
            app.PrzemieszczeniektoweLabel = uilabel(app.WagistrojeniaregulatorwPanel);
            app.PrzemieszczeniektoweLabel.Text = {'Przemieszczenie'; 'kątowe'};
            app.katoweSpinner = uispinner(app.WagistrojeniaregulatorwPanel);
            app.katoweSpinner.Value = 1;
            app.SianapduLabel = uilabel(app.WagistrojeniaregulatorwPanel);
            app.SianapduLabel.Text = {'Siła napędu'; ''};
            app.sila_napeduSpinner = uispinner(app.WagistrojeniaregulatorwPanel);
            app.sila_napeduSpinner.Value = 1;
            app.Zapis_wagButton = uibutton(app.WagistrojeniaregulatorwPanel, 'push');
            app.Zapis_wagButton.ButtonPushedFcn = createCallbackFcn(app, @Zapis_wagButtonPushed, true);
            app.Zapis_wagButton.Tooltip = {'Zmiana wag wymaga zapisania przed strojeniem lub symulacją'};
            app.Zapis_wagButton.Text = 'Zapisz';
            app.SymulacjaPanel = uipanel(app.UIFigure);
            app.SymulacjaPanel.Title = 'Symulacja';
            app.SymulacjaodwrconegowahadaButton = uibutton(app.SymulacjaPanel, 'push');
            app.SymulacjaodwrconegowahadaButton.ButtonPushedFcn = createCallbackFcn(app, @SymulacjaodwrconegowahadaButtonPushed, true);
            app.SymulacjaodwrconegowahadaButton.Text = {'Symulacja '; 'odwróconego wahadła'};
            app.TypRegulatoraButtonGroup = uibuttongroup(app.SymulacjaPanel);
            app.TypRegulatoraButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypRegulatoraButtonGroupSelectionChanged, true);
            app.TypRegulatoraButtonGroup.Title = 'Typ Regulatora';
            app.LqrButton = uiradiobutton(app.TypRegulatoraButtonGroup);
            app.LqrButton.Text = 'Lqr';
            app.LqrButton.Value = true;
            app.NumericButton = uiradiobutton(app.TypRegulatoraButtonGroup);
            app.NumericButton.Text = {'Numeryczny'; ''};
            app.StrojeniePanel = uipanel(app.UIFigure);
            app.StrojeniePanel.Title = 'Strojenie';
            app.RegulatorLQRButton = uibutton(app.StrojeniePanel, 'push');
            app.RegulatorLQRButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatorLQRButtonPushed, true);
            app.RegulatorLQRButton.Text = {'Regulator LQR'; ''};
            app.RegulatornumerycznyButton = uibutton(app.StrojeniePanel, 'push');
            app.RegulatornumerycznyButton.ButtonPushedFcn = createCallbackFcn(app, @RegulatornumerycznyButtonPushed, true);
            app.RegulatornumerycznyButton.Text = 'Regulator numeryczny';
            app.Reset_nastawButton = uibutton(app.StrojeniePanel, 'push');
            app.Reset_nastawButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_nastawButtonPushed, true);
            app.Reset_nastawButton.Tooltip = {'Zresetuj po zapisaniu  nowy parametrów odwróconego wahadła lub wag regulatora'};
            app.Reset_nastawButton.Text = {'Reset nastaw'; 'regulatora numerycznego'; ''};
            app.ZakceniePanel = uipanel(app.UIFigure);
            app.ZakceniePanel.Title = 'Zakłócenie';
            app.TypzakceniaButtonGroup = uibuttongroup(app.ZakceniePanel);
            app.TypzakceniaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypzakceniaButtonGroupSelectionChanged, true);
            app.TypzakceniaButtonGroup.Title = 'Typ zakłócenia';
            app.wymuszenieimpulsoweButton = uiradiobutton(app.TypzakceniaButtonGroup);
            app.wymuszenieimpulsoweButton.Text = {'wymuszenie '; 'impulsowe'};
            app.wymuszenieimpulsoweButton.Value = true;
            app.skokButton = uiradiobutton(app.TypzakceniaButtonGroup);
            app.skokButton.Text = {'wymuszenie'; 'skokowe'};
            app.szumbialyButton = uiradiobutton(app.TypzakceniaButtonGroup);
            app.szumbialyButton.Text = {'szum '; 'gaussowski'};
            app.wymuszeniesinusoidalneButton = uiradiobutton(app.TypzakceniaButtonGroup);
            app.wymuszeniesinusoidalneButton.Text = {'wymuszenie'; 'sinusoidalne'};
            app.UIAxes = uiaxes(app.ZakceniePanel);
            xlabel(app.UIAxes, 'czas [s]')
            ylabel(app.UIAxes, 'siła zakłócająca [N]')
            zlabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-3 3];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 2 4 6 8 10];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.YTick = [-3 -2 -1 0 1 2 3];
            app.UIAxes.YTickLabel = {'-3'; '-2'; '1'; '0'; '1'; '2'; '3'};
            app.PANELUYTKOWNIKAODWRCONEGOWAHADALabel = uilabel(app.UIFigure);
            app.PANELUYTKOWNIKAODWRCONEGOWAHADALabel.Text = 'PANEL UŻYTKOWNIKA ODWRÓCONEGO WAHADŁA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_uzytkownika

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end